classdef dataHandlingApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TerminalTextArea           matlab.ui.control.TextArea
        LiveTerminalTextAreaLabel  matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        PlotViewTab                matlab.ui.container.Tab
        PlotGrid                   matlab.ui.container.GridLayout
        YDataDropDown              matlab.ui.control.DropDown
        YdataLabel                 matlab.ui.control.Label
        XDataDropDown              matlab.ui.control.DropDown
        XDataLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        LiveDataGrid               matlab.ui.container.GridLayout
        RocketTimeField            matlab.ui.control.NumericEditField
        RocketTimeLabel            matlab.ui.control.Label
        FireCountdownField         matlab.ui.control.NumericEditField
        FillTimerField             matlab.ui.control.NumericEditField
        NOxPressureField           matlab.ui.control.NumericEditField
        IPAPressureField           matlab.ui.control.NumericEditField
        FireStatusLamp             matlab.ui.control.Lamp
        FillStatusLamp             matlab.ui.control.Lamp
        ArmStatusLamp              matlab.ui.control.Lamp
        FireCountdownLabel         matlab.ui.control.Label
        FillTimerLabel             matlab.ui.control.Label
        FireStatusLabel            matlab.ui.control.Label
        FillStatusLabel            matlab.ui.control.Label
        ArmStatusLabel             matlab.ui.control.Label
        IPAPressureLabel           matlab.ui.control.Label
        NOxPressureLabel           matlab.ui.control.Label
        RocketStatusLamp           matlab.ui.control.Lamp
        RocketStatusLabel          matlab.ui.control.Label
        ConfigPanel                matlab.ui.container.Panel
        ConfigGrid                 matlab.ui.container.GridLayout
        FileLoggingPanel           matlab.ui.container.Panel
        FileLoggingGrid            matlab.ui.container.GridLayout
        AutoRecordButton           matlab.ui.control.StateButton
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        FileSelectButton           matlab.ui.control.Button
        FilenameEditField          matlab.ui.control.EditField
        FilenameLabel              matlab.ui.control.Label
        ConfigurationPanel         matlab.ui.container.Panel
        ConfigurationGrid          matlab.ui.container.GridLayout
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        BaudrateDropDown           matlab.ui.control.DropDown
        BaudRateLabel              matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        SerialportDropDown         matlab.ui.control.DropDown
        SerialportDropDownLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)

        %Expected Data Format
        % NOxPressure, IPAPressure, RocketTimer
        % Mode: (REST|FILL|FIRE)
        % Fill Time: _s
        % Launch Time: (+|-)_s


        % App Flags
        ChecksumAlert = false           % Display warning for invalid checksum

        Plot                            % Handle to "Plot View" line object

        % File Selection and Logging
        Filename = "Comet1_Test.csv"        % Default file name for logging
        FilePath = fullfile(pwd, 'Logs')    % Default file path for logging
        RawFilename = "Comet1_Test_raw.txt" % Default name for raw logging file
        SerialPort                          % Handle to serialport object
        Logging = true                     % Boolean for whether we are logging to file or not
        FileInitialized = false

        % Live Updating Variables (latest point)
        NOxPressure     double
        IPAPressure     double
        ArmStatus       logical
        FillStatus      logical
        FireStatus      logical
        FillTimer       double
        FireCountdown   double
        RocketTime      double
        

        % Data saved over time
        NOxPressureLog
        IPAPressureLog
        ArmStatusLog      
        FillStatusLog      
        FireStatusLog     
        FillTimerLog      
        FireCountdownLog   
        RocketTimeLog     
        DataTable                       % For last 10 minutes of "live updating" data
        LastMinutes = 10                % Number of latest minutes to preserve
        LastLines = 500                 % Number of latest lines to preserve
        TableVariables = {              % Header for data table
            'ComputerTime', ...
            'NOxPressure', ...
            'IPAPressure', ...
            'ArmStatus', ...
            'FillStatus', ...
            'FillTimer', ...
            'FireStatus', ...
            'FireCountdown', ...
            'RocketTimer'};
    end

    methods (Access = private)

        function serialportCallback(app,obj,evt)
            %serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states
            % try
                % Handle Data state
                inputline = readline(obj);
                fprintf('Received: %s\n', inputline);
                % Append the line to the terminal
                currentText = app.TerminalTextArea.Value;
                newText = [currentText; {inputline}];
                app.TerminalTextArea.Value = newText;
            
                % Scroll to the bottom to show the latest data
                app.TerminalTextArea.Scrollable = 'on';
                pause(0.001);  % Small pause to allow the UI to update
                app.TerminalTextArea.ScrollTo('bottom');
    
                % Extract callback timestamp from evt data
                eventtime = datetime(evt.AbsTime,Format='yyyy-MM-dd''T''HH:mm:ss.SSS');
    
                % Process Data state
                latestdata = processData(app,inputline,eventtime);
    
                if app.Logging
                    % Log Data state
                    logData(app,inputline,latestdata);
                end
    
                % Update Live View state
                updateLiveView(app)
            % catch ME
                % warning('Error in serial callback');
        end

        function newrow = processData(app,inputline,dt)
            %processData Read and processes the newest line,
            %   save processed data to app properties for later use.
            %   This includes all of the "Live Data", "Track Data", meaning
            %   data saved over time, and the newest LastLines number of NMEA
            %   raw data sentences

            newrow = {dt};

            % Run checksum for inputline; if checksum is invalid, do not
            % process the line (return early)
            % if ~validateChecksum(app,inputline)
            %     if app.ChecksumAlert
            %         warning("Checksum is wrong for " + inputline);
            %     end
            % end

            if contains(inputline,",") 
                results = parseSensorData(app,inputline);
                updateAppProperties(app,results);
            elseif contains(inputline,"Status")
                results = parseStatusData(app,inputline);
                updateAppProperties(app,results);
            elseif contains(inputline,"Fill") || contains(inputline,"Fire")
                results = parseTimerData(app,inputline);
                updateAppProperties(app,results);     
            end

            % Update collected data over time
            newrow = {newrow{1}, app.NOxPressure, app.IPAPressure, app.ArmStatus, app.FillStatus, app.FillTimer, app.FireStatus, app.FireCountdown, app.RocketTimer};
            app.DataTable = [app.DataTable; newrow];

            % Delete times older than 10 minutes
            rowsToKeep = minutes(datetime("now") - app.DataTable.ComputerTime) < app.LastMinutes;
            app.DataTable = app.DataTable(rowsToKeep,:);

        end


        function updateAppProperties(app,section,values)
            %updateAppProperties Update the app properties for the input
            %   sentence based on the given values. For example, if sentence
            %   was "GPGGA", then the GPGGA-related app properties will be
            %   updated. values contains a struct of the necessary data.

            switch section
                case "sensors"
                    app.NOxPressure = values.NOxPressure;
                    app.IPAPressure = values.IPAPressure;
                    app.RocketTime = values.RocketTime;

                    app.NOxPressureLog(end+1) = values.NOxPressure;
                    app.IPAPressureLog(end+1) = values.IPAPressure;
                    app.RocketTimeLog(end+1) = values.RocketTime;
                case "status"
                    app.ArmStatus = values.ArmStatus;
                    app.FillStatus = values.FillStatus;
                    app.FireStatus = values.FireStatus;

                    app.ArmStatusLog(end+1) = values.ArmStatus;
                    app.FillStatusLog(end+1) = values.FillStatus;
                    app.FireStatusLog(end+1) = values.FireStatus;
                case "timer"
                    app.FillTimer = values.FillTimer;
                    app.FireCountdown = values.FireCountdown;

                    app.FillTimerLog(end+1) = values.FillTimer;
                    app.FireCountdownLog(end+1) = values.FireCountdown;
            end
           
        end

        function updateLiveView(app)
            %updateLiveView will update the Live Data view and various
            %   tabs (depending on which tab is open) to match the "current data"

            updateLiveDataFields(app);
            % Update graphics based on open tab group
            switch app.TabGroup.SelectedTab.Title
                case "Plot View"
                    updatePlotView(app);
            end

        end

        function setAppState(app,state)
            %setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialportDropDown.Enable = "on";
                    app.BaudrateDropDown.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.RefreshButton.Enable = "on";
                    app.AutoRecordButton.Enable = "on";

                    app.DisconnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    
                    app.XDataDropDown.Enable = "off";
                    app.YDataDropDown.Enable = "off";
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.AutoRecordButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "WaitForData"
                    % Not logging
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.AutoRecordButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";
                    app.FilenameEditField.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "Logging"
                    app.DisconnectButton.Enable = "on";
                    app.StopButton.Enable = "on";
                    app.XDataDropDown.Enable = "on";
                    app.YDataDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.AutoRecordButton.Enable = "off";
                    app.FilenameEditField.Enable = "off";
                    app.RefreshButton.Enable = "off";
            end
        end

        function results = validateChecksum(~,inputline)
            %validateChecksum confirms that the latest line has a valid
            %   checksum calculation

            if ~startsWith(inputline,"$")
                results = 0;
                return
            end
            splitline = split(inputline,["$" "*"]);
            checksumline = uint8(splitline{2});
            expected = hex2dec(splitline(3));
            checksum = uint8(0);
            % Iterate through each character in checksumline, executing a
            % bit-wise XOR across all characters to validate the checksum
            for character = checksumline
                checksum = bitxor(checksum,character);
            end
            results = checksum == expected;
        end


        function updatePlotView(app)
            %updatePlotView is a helper function for the Plot View tab

            % filter = seconds(datetime("now")-app.DataTable.ComputerTime) < app.XDataDropDown.Value;
            filter = seconds(app.DataTable.RocketTime - min(app.DataTable.RocketTime)) < app.XDataDropDown.Value;
            x = app.DataTable.RocketTime(filter);
            y = app.DataTable{filter,app.YDataDropDown.Value,0};
            set(app.Plot,"XData",x,"YData",y);

            % xlim(app.UIAxes, 'auto');
            % ylim(app.UIAxes, 'auto');
        end


        function datastruct = parseSensorData(~,inputline)
            %parseSensorData is a helper function to read sensor data
            %    parseSensorData takes in a serialport line
            %    and returns the NOxPressure, IPAPressure, RocketTimer as a struct.

            datastruct = struct("NOxPressure",NaN,"IPAPressure",NaN,"RocketTimer",NaN);
            splitdata = split(inputline,",");
            datastruct.NOxPressure = str2double(splitdata{1});
            datastruct.IPAPressure = str2double(splitdata{2});
            datastruct.RocketTimer = str2double(splitdata{3});
        end

        function datastruct = parseStatusData(~,inputline)
            %parseStatusData is a helper function to read rocket status
            %    parseSensorData takes in a serialport line as Status: (!)(Arm|Fill|Fire)
            %    and changes the associated status.

            datastruct = struct("ArmStatus",NaN,"FillStatus",NaN,"FireStatus",NaN);


            if contains(inputline,"REST")
                datastruct.ArmStatus = true;
                datastruct.FillStatus = false;
                datastruct.FireStatus = false;
            elseif contains(inputline,"FILL")
                datastruct.ArmStatus = false;
                datastruct.FillStatus = true;
                datastruct.FireStatus = false;
                   
            elseif contains(inputline,"FIRE")    
                datastruct.ArmStatus = false;
                datastruct.FillStatus = false;
                datastruct.FireStatus = true;
            end

        end

        function datastruct = parseTimerData(~,inputline)
            %parseSensorData is a helper function to read sensor data
            %    parseSensorData takes in a serialport line as Fill: _s or Fire: (+|-)_s
            %    and returns the timer value as a struct.

            datastruct = struct("FillTimer",NaN,"FireCountdown",NaN);

            if contains(inputline,"Fill")
                datastruct.FillTimer = sscanf(inputline, 'Fill Time: %d s');  %might need %2d instead?
            elseif contains(inputline,"Launch")
                datastruct.FireCountdown = sscanf(inputline, 'Launch Time: %d s');  %might need %f instead?
            end

        end


        function logData(app,inputline,row)
            %logData Represents the Log Data state for app. If FileInitialized is
            %   false, initialize files and log to file. Otherwise, just log to
            %   file

            if ~app.FileInitialized
                initializeProcessedDataFile(app);
            end
            logToFile(app,inputline,row);
        end

        function logToFile(app,inputline,row)
            %logToFile Write to raw data file and processed data file. Because
            %   inputline is a single line, use writelines for the raw file.
            %   Because row is a cell array, use writecell for processed file.

            % Log raw inputs to txt
            dt = row{1};
            writelines(string(dt)+" "+inputline,app.RawFilename,WriteMode="append")

            % Log parsed data
            rowTable = cell2table(row, 'VariableNames', app.TableVariables);
            writetable(rowTable, app.FilenameEditField.Value, 'WriteMode', 'append');
        end

        function initializeProcessedDataFile(app)
            %initializeProcessedDataFile Write header to file that logs all
            %   data (not raw file). If there is an error, disable logging
            %   and display error. Allow file logging with FileInitialized.

            % try
            %     writecell(app.TableVariables,app.FilenameEditField.Value,WriteMode="append")
            %     app.FileInitialized = true;
            % catch ME
            %     uialert(app.UIFigure,ME.message,"File Error",Interpreter="html")
            %     app.Logging = false;
            % end
            if ~isfolder(app.FilePath)
                    mkdir(app.FilePath);
            end
            try
                % Create an empty table with the correct headers
                emptyTable = cell2table(cell(0, numel(app.TableVariables)), 'VariableNames', app.TableVariables);
                writetable(emptyTable, app.FilenameEditField.Value, 'WriteMode', 'append');
                app.FileInitialized = true;
            catch ME
                uialert(app.UIFigure, ME.message, "File Error", Interpreter="html");
                app.Logging = false;
            end

        end

        function [processedfile, rawfile] = createFileName(app,filename)
            %createFileName Check if the file name and corresponding raw file
            %   name meets expected format, meaning its extension is .TXT and
            %   it is not an already existing file. If the file name exists,
            %   return new, auto-indexed name.
            %   For example, if '<datafolder>\GPS_data.txt' exists, return the
            %   next indexed file name in the sequence that does not exist,
            %   such as '<datafolder>\GPS_data_2.txt'

            [filepath, name, ext] = fileparts(filename);
            if ~strcmpi(ext,".csv")
                uialert(app.UIFigure,"File must be in .csv format","File Log Error")
                ext = ".csv";
            end
            ind = 0;
            processedfile = fullfile(filepath,strcat(name,ext));
            rawfile = fullfile(filepath,strcat(name,"_raw.txt"));
            while isfile(processedfile) || isfile(rawfile)
                ind = ind + 1;
                processedfile = fullfile(filepath,strcat(name,"_",num2str(ind),ext));
                rawfile = fullfile(filepath,strcat(name,"_",num2str(ind),"_raw.txt"));
            end
        end

        function updateLiveDataFields(app)
            %updateLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to their respective app property
            %   values. Enable the lamp indicating rocket status (valid data receipt)

            app.RocketStatusLamp.Color = [0 1 0];
            app.NOxPressureField = app.NOxPressure;
            app.IPAPressureField = app.IPAPressure;

            if app.ArmStatus
                app.ArmStatusLamp.Color = [0 0 1];
            else
                app.ArmStatusLamp.Color = [0.9 0.9 0.9];
            end
            if app.FillStatus
                app.FillStatusLamp.Color = [0 0 1];
            else
                app.FillStatusLamp.Color = [0.9 0.9 0.9];
            end
            if app.FireStatus
                app.FireStatusLamp.Color = [1 0 0];
            else
                app.FireStatusLamp.Color = [0.9 0.9 0.9];
            end

            app.FillTimerField = app.FillTimer;
            app.FireCountdownField = app.FireCountdown;
            app.RocketTimeField = app.RocketTime;
        end

        function clearLiveDataFields(app)
            %clearLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to be empty values. Disable the lamp
            %   indicating rocket status, since there is no data to show.

            app.RocketStatusLamp.Color = [0.9 0.9 0.9];
            app.NOxPressureField.Value = [];
            app.IPAPressureField.Value = [];
            app.ArmStatusLamp.Color = [0.9 0.9 0.9];
            app.FillStatusLamp.Color = [0.9 0.9 0.9];
            app.FireStatusLamp.Color = [0.9 0.9 0.9];
            app.FillTimerField.Value = [];
            app.FireCountdownField.Value = [];
            app.RocketTimeField.Value = [];
        end

        function resetData(app)
            %resetData Reset all of the saved data from the app properties.

            app.NOxPressure = 0;
            app.IPAPressure = 0;
            app.ArmStatus = false;
            app.FillStatus = false;
            app.FireStatus = false;
            app.FillTimer = 0;
            app.FireCountdown = 0;
            app.RocketTime = 0;

            app.Logging = false;
            app.FileInitialized = false;
            app.NOxPressureLog = [];
            app.IPAPressureLog = [];
            app.ArmStatusLog = [];
            app.FillStatusLog = [];
            app.FireStatusLog = [];
            app.FillTimerLog = [];
            app.FireCountdownLog = [];
            app.RocketTimeLog = [];            
            app.DataTable = table(datetime("now"),0,0,0,0,0,0,0,0,VariableNames=app.TableVariables);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)

            % Set up components
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
            app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);
            app.Plot = plot(app.UIAxes,[], [], '.-');
            ylim(app.UIAxes,"padded")
            xlim(app.UIAxes,"padded")
            setAppState(app,"DeviceSelection");
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            setAppState(app,"Connected");
            drawnow

            % Validate selected serial port device
            try
                fprintf('Connecting to %s at %d baud...\n', app.SerialportDropDown.Value, str2double(app.BaudrateDropDown.Value));
                app.SerialPort = serialport(app.SerialportDropDown.Value,str2double(app.BaudrateDropDown.Value));
            catch ME
                fprintf('Connection failed');
                uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                setAppState(app,"DeviceSelection");
                return
            end

            % Set terminator and callback
            configureTerminator(app.SerialPort,"CR/LF")
            configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))
            fprintf('Connected successfully!\n');
            % Reset app properties
            resetData(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.SerialPort)
            StopButtonPushed(app);
            setAppState(app,"DeviceSelection");
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            [name, raw] = createFileName(app,app.FilenameEditField.Value);
            % Reset FileInitialized only when the file name is changed
            % if the file name is not changed, continue to write to the
            % existing file, appending new data to the end
            app.FileInitialized = false;
            app.FilenameEditField.Value = name;
            app.RawFilename = raw;
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            [app.Filename, app.FilePath] = uiputfile(".csv");
            figure(app.UIFigure)
            if ~isequal(app.Filename,0) && ~isequal(app.FilePath,0)
                app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If there is no file initialized, make sure to check for a
            % valid file name before starting to log
            if ~app.FileInitialized
                [name, raw] = createFileName(app,app.FilenameEditField.Value);
                app.FilenameEditField.Value = name;
                app.RawFilename = raw;
            end
            
            app.Logging = true;

            % Reset log data
            app.NOxPressureLog = [];
            app.IPAPressureLog = [];
            app.ArmStatusLog = [];
            app.FillStatusLog = [];
            app.FireStatusLog = [];
            app.FillTimerLog = [];
            app.FireCountdownLog = [];
            app.RocketTimeLog = [];   
            setAppState(app,"Logging");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Logging = false;
            % Do not change FileInitialized, as keeping the file name
            % will mean you continue logging to the same file
            setAppState(app,"WaitForData");
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab
                case "PlotViewTab"
                    updatePlotView(app);
            end
        end

        % Value changed function: XDataDropDown
        function XDataDropDownValueChanged(app, event)
            updatePlotView(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = "";
            if ~isempty(app.SerialPort) && isvalid(app.SerialPort)
                selection = uiconfirm(app.UIFigure,"Close app and serial port connection?","Confirm Close");
            end
            if selection ~= "Cancel"
                delete(app)
            end
        end

        % Value changed function: YDataDropDown
        function YDataDropDownValueChanged(app, event)
            ylabel(app.UIAxes,app.YDataDropDown.Value)
            updatePlotView(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LiveTerminalTextAreaLabel = uilabel(app.LeftPanel);
            app.LiveTerminalTextAreaLabel.Text = 'Live Terminal';
            app.TerminalTextArea = uitextarea(app.LeftPanel);
            app.TerminalTextArea.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.ConfigPanel = uipanel(app.GridLayout2);
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.ConfigurationPanel = uipanel(app.ConfigGrid);
            app.ConfigurationPanel.Title = 'Configuration';
            app.ConfigurationGrid = uigridlayout(app.ConfigurationPanel);
            app.SerialportDropDownLabel = uilabel(app.ConfigurationGrid);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigurationGrid);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.Placeholder = 'Select...';
            app.SerialportDropDown.Value = {};
            app.RefreshButton = uibutton(app.ConfigurationGrid, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '';
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'refresh.svg');
            app.BaudRateLabel = uilabel(app.ConfigurationGrid);
            app.BaudRateLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.ConfigurationGrid);
            app.BaudrateDropDown.Items = {'4800', '9600', '19200', '115200'};
            app.BaudrateDropDown.Editable = 'on';
            app.BaudrateDropDown.Placeholder = 'Select...';
            app.BaudrateDropDown.Value = '4800';
            app.ConnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.FileLoggingPanel = uipanel(app.ConfigGrid);
            app.FileLoggingPanel.Title = 'File Logging';
            app.FileLoggingGrid = uigridlayout(app.FileLoggingPanel);
            app.FilenameLabel = uilabel(app.FileLoggingGrid);
            app.FilenameLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileLoggingGrid, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'GPS_data.txt';
            app.FileSelectButton = uibutton(app.FileLoggingGrid, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Text = '';
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'open.svg');
            app.StartButton = uibutton(app.FileLoggingGrid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.FileLoggingGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.AutoRecordButton = uibutton(app.FileLoggingGrid, 'state');
            app.AutoRecordButton.Text = 'AutoStartRecord';
            app.LiveDataGrid = uigridlayout(app.GridLayout2);
            app.RocketStatusLabel = uilabel(app.LiveDataGrid);
            app.RocketStatusLabel.Text = 'Rocket Status:';
            app.RocketStatusLamp = uilamp(app.LiveDataGrid);
            app.NOxPressureLabel = uilabel(app.LiveDataGrid);
            app.NOxPressureLabel.Text = 'NOx Pressure:';
            app.IPAPressureLabel = uilabel(app.LiveDataGrid);
            app.IPAPressureLabel.Text = 'IPA Pressure:';
            app.ArmStatusLabel = uilabel(app.LiveDataGrid);
            app.ArmStatusLabel.Text = 'Arm Status:';
            app.FillStatusLabel = uilabel(app.LiveDataGrid);
            app.FillStatusLabel.Text = 'Fill Status:';
            app.FireStatusLabel = uilabel(app.LiveDataGrid);
            app.FireStatusLabel.Text = 'Fire Status:';
            app.FillTimerLabel = uilabel(app.LiveDataGrid);
            app.FillTimerLabel.Text = 'Fill Timer:';
            app.FireCountdownLabel = uilabel(app.LiveDataGrid);
            app.FireCountdownLabel.Text = 'Fire Countdown:';
            app.ArmStatusLamp = uilamp(app.LiveDataGrid);
            app.FillStatusLamp = uilamp(app.LiveDataGrid);
            app.FireStatusLamp = uilamp(app.LiveDataGrid);
            app.IPAPressureField = uieditfield(app.LiveDataGrid, 'numeric');
            app.IPAPressureField.AllowEmpty = 'on';
            app.IPAPressureField.Editable = 'off';
            app.IPAPressureField.Value = [];
            app.NOxPressureField = uieditfield(app.LiveDataGrid, 'numeric');
            app.NOxPressureField.AllowEmpty = 'on';
            app.NOxPressureField.Editable = 'off';
            app.NOxPressureField.Value = [];
            app.FillTimerField = uieditfield(app.LiveDataGrid, 'numeric');
            app.FillTimerField.AllowEmpty = 'on';
            app.FillTimerField.Editable = 'off';
            app.FillTimerField.Value = [];
            app.FireCountdownField = uieditfield(app.LiveDataGrid, 'numeric');
            app.FireCountdownField.AllowEmpty = 'on';
            app.FireCountdownField.Editable = 'off';
            app.FireCountdownField.Value = [];
            app.RocketTimeLabel = uilabel(app.LiveDataGrid);
            app.RocketTimeLabel.Text = 'Rocket Time:';
            app.RocketTimeField = uieditfield(app.LiveDataGrid, 'numeric');
            app.RocketTimeField.AllowEmpty = 'on';
            app.RocketTimeField.Editable = 'off';
            app.RocketTimeField.Value = [];
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PlotViewTab = uitab(app.TabGroup);
            app.PlotViewTab.Title = 'Plot View';
            app.PlotGrid = uigridlayout(app.PlotViewTab);
            app.UIAxes = uiaxes(app.PlotGrid);
            title(app.UIAxes, 'Live Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pressure')
            zlabel(app.UIAxes, 'Z')
            app.XDataLabel = uilabel(app.PlotGrid);
            app.XDataLabel.Text = 'X data';
            app.XDataDropDown = uidropdown(app.PlotGrid);
            app.XDataDropDown.Items = {'30 sec', '1 min', '5 min', '10 min'};
            app.XDataDropDown.ItemsData = [30 60 300 600];
            app.XDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XDataDropDownValueChanged, true);
            app.XDataDropDown.Value = 30;
            app.YdataLabel = uilabel(app.PlotGrid);
            app.YdataLabel.Text = 'Y data';
            app.YDataDropDown = uidropdown(app.PlotGrid);
            app.YDataDropDown.Items = {'NOxPressure'};
            app.YDataDropDown.ItemsData = {'NOxPressure'};
            app.YDataDropDown.ValueChangedFcn = createCallbackFcn(app, @YDataDropDownValueChanged, true);
            app.YDataDropDown.Value = 'NOxPressure';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataHandlingApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end